classdef TTTGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GameStateTextAreaLabel   matlab.ui.control.Label
        GameStateTextArea        matlab.ui.control.TextArea
        PlayerTurnTextAreaLabel  matlab.ui.control.Label
        PlayerTurnTextArea       matlab.ui.control.TextArea
        GameGridLabel            matlab.ui.control.Label
        Cell1Button              matlab.ui.control.Button
        Cell2Button              matlab.ui.control.Button
        Cell3Button              matlab.ui.control.Button
        Cell4Button              matlab.ui.control.Button
        Cell7Button              matlab.ui.control.Button
        Cell5Button              matlab.ui.control.Button
        Cell6Button              matlab.ui.control.Button
        Cell8Button              matlab.ui.control.Button
        Cell9Button              matlab.ui.control.Button
        TicTacToeGUILabel        matlab.ui.control.Label
        GameDetailsLabel         matlab.ui.control.Label
        GameOptionsLabel         matlab.ui.control.Label
        PauseGameButton_2        matlab.ui.control.Button
        ResumeGameButton         matlab.ui.control.Button
        EndGameButton            matlab.ui.control.Button
        GameErrorsTextAreaLabel  matlab.ui.control.Label
        GameErrorsTextArea       matlab.ui.control.TextArea
        Lamp                     matlab.ui.control.Lamp
        Lamp_2                   matlab.ui.control.Lamp
        Lamp_3                   matlab.ui.control.Lamp
        Lamp_4                   matlab.ui.control.Lamp
        Lamp_5                   matlab.ui.control.Lamp
        Lamp_6                   matlab.ui.control.Lamp
        Lamp_7                   matlab.ui.control.Lamp
        Lamp_8                   matlab.ui.control.Lamp
        Lamp_9                   matlab.ui.control.Lamp
    end

    properties (Access = public)
        PauseGameButtonVal = 0;
        ResumeGameButtonVal = 0;
        EndGameButtonVal = 0;
        CellOneVal = 0;
        CellTwoVal = 0;
        CellThreeVal = 0;
        CellFourVal = 0;
        CellFiveVal = 0;
        CellSixVal = 0;
        CellSevenVal = 0;
        CellEightVal = 0;
        CellNineVal = 0;
    end


    methods (Access = private)

        % Button pushed function: PauseGameButton_2
        function PauseGameButtonPushed(app, event)
            if(app.EndGameButtonVal == 0)
                app.GameStateTextArea.Value = 'Game Paused...';
            elseif (app.EndGameButtonVal == 1)
                app.GameStateTextArea.Value = 'Game has Ended...';
            end
            app.PauseGameButtonVal = 1;
            app.ResumeGameButtonVal = 0;
        end

        % Button pushed function: ResumeGameButton
        function ResumeGameButtonPushed(app, event)
            if(app.EndGameButtonVal == 0)
                app.GameStateTextArea.Value = 'Game Running...';
            elseif (app.EndGameButtonVal == 1)
                app.GameStateTextArea.Value = 'Game has Ended...';
            end
            app.ResumeGameButtonVal = 1;
            app.PauseGameButtonVal = 0;
        end

        % Button pushed function: EndGameButton
        function EndGameButtonPushed(app, event)
            if(app.EndGameButtonVal == 0)
                app.GameStateTextArea.Value = 'Game Ending...';
            elseif (app.EndGameButtonVal == 1)
                app.GameStateTextArea.Value = 'Game has Ended...';
            end
            app.EndGameButtonVal = 1;
            
            app.delete;
        end

        % Button pushed function: Cell1Button
        function Cell1ButtonPushed(app, event)
            if(app.CellOneVal == 1)
                app.GameErrorsTextArea.Value = 'This has already been selected! Please choose another box.';
            elseif(app.CellOneVal == 0 && app.PauseGameButtonVal ==1)
                app.GameErrorsTextArea.Value = 'Cannot make move! Game is paused.';
            else 
                app.CellOneVal = 1;
                app.GameErrorsTextArea.Value = 'No Errors!';
                app.Lamp.Enable = 'off';
            end
        end

        % Button pushed function: Cell2Button
        function Cell2ButtonPushed(app, event)
            if(app.CellTwoVal == 1)
                app.GameErrorsTextArea.Value = 'This has already been selected! Please choose another box.';
            elseif(app.CellTwoVal == 0 && app.PauseGameButtonVal ==1)
                app.GameErrorsTextArea.Value = 'Cannot make move! Game is paused.';
            else 
                app.CellTwoVal = 1;
                app.GameErrorsTextArea.Value = 'No Errors!';
                app.Lamp_2.Enable = 'off';
            end
        end

        % Button pushed function: Cell3Button
        function Cell3ButtonPushed(app, event)
            if(app.CellThreeVal == 1)
                app.GameErrorsTextArea.Value = 'This has already been selected! Please choose another box.';
            elseif(app.CellThreeVal == 0 && app.PauseGameButtonVal ==1)
                app.GameErrorsTextArea.Value = 'Cannot make move! Game is paused.';
            else 
                app.CellThreeVal = 1;
                app.GameErrorsTextArea.Value = 'No Errors!';
                app.Lamp_3.Enable = 'off';
            end
        end

        % Button pushed function: Cell4Button
        function Cell4ButtonPushed(app, event)
            if(app.CellFourVal == 1)
                app.GameErrorsTextArea.Value = 'This has already been selected! Please choose another box.';
            elseif(app.CellFourVal == 0 && app.PauseGameButtonVal ==1)
                app.GameErrorsTextArea.Value = 'Cannot make move! Game is paused.';
            else 
                app.CellFourVal = 1;
                app.GameErrorsTextArea.Value = 'No Errors!';
                app.Lamp_4.Enable = 'off';
            end
        end

        % Button pushed function: Cell5Button
        function Cell5ButtonPushed(app, event)
            if(app.CellFiveVal == 1)
                app.GameErrorsTextArea.Value = 'This has already been selected! Please choose another box.';
            elseif(app.CellFiveVal == 0 && app.PauseGameButtonVal ==1)
                app.GameErrorsTextArea.Value = 'Cannot make move! Game is paused.';
            else 
                app.CellFiveVal = 1;
                app.GameErrorsTextArea.Value = 'No Errors!';
                app.Lamp_5.Enable = 'off';
            end
        end

        % Button pushed function: Cell6Button
        function Cell6ButtonPushed(app, event)
            if(app.CellSixVal == 1)
                app.GameErrorsTextArea.Value = 'This has already been selected! Please choose another box.';
            elseif(app.CellSixVal == 0 && app.PauseGameButtonVal ==1)
                app.GameErrorsTextArea.Value = 'Cannot make move! Game is paused.';
            else 
                app.CellSixVal = 1;
                app.GameErrorsTextArea.Value = 'No Errors!';
                app.Lamp_6.Enable = 'off';
            end
        end

        % Button pushed function: Cell7Button
        function Cell7ButtonPushed(app, event)
            if(app.CellSevenVal == 1)
                app.GameErrorsTextArea.Value = 'This has already been selected! Please choose another box.';
            elseif(app.CellSevenVal == 0 && app.PauseGameButtonVal ==1)
                app.GameErrorsTextArea.Value = 'Cannot make move! Game is paused.';
            else 
                app.CellSevenVal = 1;
                app.GameErrorsTextArea.Value = 'No Errors!';
                app.Lamp_7.Enable = 'off';
            end
        end

        % Button pushed function: Cell8Button
        function Cell8ButtonPushed(app, event)
            if(app.CellEightVal == 1)
                app.GameErrorsTextArea.Value = 'This has already been selected! Please choose another box.';
            elseif(app.CellEightVal == 0 && app.PauseGameButtonVal ==1)
                app.GameErrorsTextArea.Value = 'Cannot make move! Game is paused.';
            else 
                app.CellEightVal = 1;
                app.GameErrorsTextArea.Value = 'No Errors!';
                app.Lamp_8.Enable = 'off';
            end
        end

        % Button pushed function: Cell9Button
        function Cell9ButtonPushed(app, event)
            if(app.CellNineVal == 1)
                app.GameErrorsTextArea.Value = 'This has already been selected! Please choose another box.';
            elseif(app.CellNineVal == 0 && app.PauseGameButtonVal ==1)
                app.GameErrorsTextArea.Value = 'Cannot make move! Game is paused.';
            else 
                app.CellNineVal = 1;
                app.GameErrorsTextArea.Value = 'No Errors!';
                app.Lamp_9.Enable = 'off';
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.GameStateTextAreaLabel = uilabel(app.UIFigure);
            app.GameStateTextAreaLabel.Text = 'Game State';
            app.GameStateTextArea = uitextarea(app.UIFigure);
            app.GameStateTextArea.Value = {'Game Running...'};
            app.PlayerTurnTextAreaLabel = uilabel(app.UIFigure);
            app.PlayerTurnTextAreaLabel.Text = 'Player Turn';
            app.PlayerTurnTextArea = uitextarea(app.UIFigure);
            app.PlayerTurnTextArea.Value = {'Player One Turn'};
            app.GameGridLabel = uilabel(app.UIFigure);
            app.GameGridLabel.Text = 'Game Grid';
            app.Cell1Button = uibutton(app.UIFigure, 'push');
            app.Cell1Button.ButtonPushedFcn = createCallbackFcn(app, @Cell1ButtonPushed, true);
            app.Cell1Button.Text = 'Cell 1';
            app.Cell2Button = uibutton(app.UIFigure, 'push');
            app.Cell2Button.ButtonPushedFcn = createCallbackFcn(app, @Cell2ButtonPushed, true);
            app.Cell2Button.Text = 'Cell 2';
            app.Cell3Button = uibutton(app.UIFigure, 'push');
            app.Cell3Button.ButtonPushedFcn = createCallbackFcn(app, @Cell3ButtonPushed, true);
            app.Cell3Button.Text = 'Cell 3';
            app.Cell4Button = uibutton(app.UIFigure, 'push');
            app.Cell4Button.ButtonPushedFcn = createCallbackFcn(app, @Cell4ButtonPushed, true);
            app.Cell4Button.Text = 'Cell 4';
            app.Cell7Button = uibutton(app.UIFigure, 'push');
            app.Cell7Button.ButtonPushedFcn = createCallbackFcn(app, @Cell7ButtonPushed, true);
            app.Cell7Button.Text = 'Cell 7';
            app.Cell5Button = uibutton(app.UIFigure, 'push');
            app.Cell5Button.ButtonPushedFcn = createCallbackFcn(app, @Cell5ButtonPushed, true);
            app.Cell5Button.Text = 'Cell 5';
            app.Cell6Button = uibutton(app.UIFigure, 'push');
            app.Cell6Button.ButtonPushedFcn = createCallbackFcn(app, @Cell6ButtonPushed, true);
            app.Cell6Button.Text = 'Cell 6';
            app.Cell8Button = uibutton(app.UIFigure, 'push');
            app.Cell8Button.ButtonPushedFcn = createCallbackFcn(app, @Cell8ButtonPushed, true);
            app.Cell8Button.Text = 'Cell 8';
            app.Cell9Button = uibutton(app.UIFigure, 'push');
            app.Cell9Button.ButtonPushedFcn = createCallbackFcn(app, @Cell9ButtonPushed, true);
            app.Cell9Button.Text = 'Cell 9';
            app.TicTacToeGUILabel = uilabel(app.UIFigure);
            app.TicTacToeGUILabel.Text = 'Tic - Tac - Toe GUI';
            app.GameDetailsLabel = uilabel(app.UIFigure);
            app.GameDetailsLabel.Text = 'Game Details';
            app.GameOptionsLabel = uilabel(app.UIFigure);
            app.GameOptionsLabel.Text = 'Game Options';
            app.PauseGameButton_2 = uibutton(app.UIFigure, 'push');
            app.PauseGameButton_2.ButtonPushedFcn = createCallbackFcn(app, @PauseGameButtonPushed, true);
            app.PauseGameButton_2.Text = 'Pause Game';
            app.ResumeGameButton = uibutton(app.UIFigure, 'push');
            app.ResumeGameButton.ButtonPushedFcn = createCallbackFcn(app, @ResumeGameButtonPushed, true);
            app.ResumeGameButton.Text = 'Resume Game';
            app.EndGameButton = uibutton(app.UIFigure, 'push');
            app.EndGameButton.ButtonPushedFcn = createCallbackFcn(app, @EndGameButtonPushed, true);
            app.EndGameButton.Text = 'End Game';
            app.GameErrorsTextAreaLabel = uilabel(app.UIFigure);
            app.GameErrorsTextAreaLabel.Text = 'Game Errors';
            app.GameErrorsTextArea = uitextarea(app.UIFigure);
            app.GameErrorsTextArea.Value = {'No Errors!'};
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp_2 = uilamp(app.UIFigure);
            app.Lamp_3 = uilamp(app.UIFigure);
            app.Lamp_4 = uilamp(app.UIFigure);
            app.Lamp_5 = uilamp(app.UIFigure);
            app.Lamp_6 = uilamp(app.UIFigure);
            app.Lamp_7 = uilamp(app.UIFigure);
            app.Lamp_8 = uilamp(app.UIFigure);
            app.Lamp_9 = uilamp(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = TTTGUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end